classdef speaker_tuner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image            matlab.ui.control.Image
        UITable          matlab.ui.control.Table
        MODESwitch       matlab.ui.control.Switch
        MODESwitchLabel  matlab.ui.control.Label
        RUNButton        matlab.ui.control.Button
        referenceButton  matlab.ui.control.Button
        EditField        matlab.ui.control.EditField
        EditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        a;
        b;
        c;
        xHz = [20 25 31.5 40 50 63 80 100 125 160 200 250 315 400 500 630 800 1000 1250 1600 2000 2500 3150 4000 5000 6300 8000 10000 12500 16000 20000];
        locsh;
        pksh;
        meanpksl;
    end
    
    methods (Access = private)
        
        function results = run_main_mode(app)

            fs = 44100;
            samples = [5*fs,10*fs];
            clear pn1 pn2 pn3 fs;
            [pn1,fs] = audioread('sample1.wav',samples);
            [pn2,fs] = audioread('sample2.wav',samples);
            [pn3,fs] = audioread('sample3.wav',samples);

            flims = [20 20e3];
            bpo = 3;
            opts = {'FrequencyLimits',flims,'BandsPerOctave',bpo};

            poctave(pn1,fs,opts{:});
            savefig('data1.fig');
            close(gcf);
            poctave(pn2,fs,opts{:});
            savefig('data2.fig');
            close(gcf);
            poctave(pn3,fs,opts{:});
            savefig('data3.fig');
            close(gcf);

            hfig1 = open('data1.fig');
            ax1 = hfig1.Children;
            x1 = ax1.Children.XData;
            y1 = ax1.Children.YData;
            close(gcf);

            hfig2 = open('data2.fig');
            ax2 = hfig2.Children;
            x2 = ax2.Children.XData;
            y2 = ax2.Children.YData;
            close(gcf);

            hfig3 = open('data3.fig');
            ax3 = hfig3.Children;
            x3 = ax3.Children.XData;
            y3 = ax3.Children.YData;
            close(gcf);

            meany = (y1+y2+y3)/3;
            [pks,locs] = findpeaks(meany);

            threshold = mean(pks);
            [app.pksh,app.locsh] = findpeaks(meany,'MinPeakHeight',threshold);

            plot(meany);
            hold on;
            plot(app.locsh,meany(app.locsh),'ro');
            hold off;
            title('Frequency Response');
            xlabel('Sequence Number') ;
            ylabel('dB');
            savefig('result.fig');
            close(gcf);

            app.meanpksl = (sum(pks)-sum(app.pksh))/(numel(pks)-numel(app.pksh));
            maxpksh = maxk(app.pksh,3);

            for index = 1:numel(app.locsh)
                if app.pksh(index)==maxpksh(1)
                    app.a = index;
                elseif app.pksh(index)==maxpksh(2)
                    app.b = index;
                elseif app.pksh(index)==maxpksh(3)
                    app.c = index;
                end
            end

            startupFcn(app);

            audiowrite('result1.wav',pn1,fs);
            audiowrite('result2.wav',pn2,fs);
            audiowrite('result3.wav',pn3,fs);
         end
        
         function results = run_sub_mode(app)
            
            fs = 44100;
            samples = [5*fs,10*fs];
            clear pn fs;
            [pn,fs] = audioread('sample_sub.wav',samples);

            flims = [20 20e3];
            bpo = 3;
            opts = {'FrequencyLimits',flims,'BandsPerOctave',bpo};

            poctave(pn,fs,opts{:});
            savefig('data_sub.fig');
            close(gcf);

            hfig = open('data_sub.fig');
            ax_sub = hfig.Children;
            x_sub = ax_sub.Children.XData;
            y_sub = ax_sub.Children.YData;
            close(gcf);

            [pks,locs] = findpeaks(y_sub);

            threshold = mean(pks);
            [app.pksh,app.locsh] = findpeaks(y_sub,'MinPeakHeight',threshold);

            plot(y_sub);
            hold on;
            plot(app.locsh,y_sub(app.locsh),'ro');
            hold off;
            title('Frequency Response');
            xlabel('Sequence Number') ;
            ylabel('dB');
            savefig('result_sub.fig');
            close(gcf);

            app.meanpksl = (sum(pks)-sum(app.pksh))/(numel(pks)-numel(app.pksh));
            maxpksh = maxk(app.pksh,3);

            for index = 1:numel(app.locsh)
                if app.pksh(index)==maxpksh(1)
                    app.a = index;
                elseif app.pksh(index)==maxpksh(2)
                    app.b = index;
                elseif app.pksh(index)==maxpksh(3)
                    app.c = index;
                end
            end

            startupFcn(app);

            audiowrite('result_sub.wav',pn,fs);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isempty(app.a)==0 && isempty(app.b)==0 && isempty(app.c)==0
                app.UITable.Data={app.xHz(app.locsh(app.a)),app.pksh(app.a)-app.meanpksl;app.xHz(app.locsh(app.b)),app.pksh(app.b)-app.meanpksl;app.xHz(app.locsh(app.c)),app.pksh(app.c)-app.meanpksl};
            elseif isempty(app.a)==0 && isempty(app.b)==0
                app.UITable.Data={app.xHz(app.locsh(app.a)),app.pksh(app.a)-app.meanpksl;app.xHz(app.locsh(app.b)),app.pksh(app.b)-app.meanpksl};
            elseif isempty(app.a)==0
                app.UITable.Data={app.xHz(app.locsh(app.a)),app.pksh(app.a)-app.meanpksl};
            end
        end

        % Button pushed function: referenceButton
        function referenceButtonPushed(app, event)
            rootDir = uigetdir(pwd, 'Specify the directory to analyze');
            % 「キャンセル」が押された場合
            if(rootDir==0)
                return
            end
            app.EditField.Value = rootDir;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            cd(app.EditField.Value);
            value = app.MODESwitch.Value;
            switch value
                case 'MAIN'
                    run_main_mode(app);
                    hfig = open('result.fig');
                    saveas(hfig,'result.jpg');
                    app.Image.ImageSource='result.jpg';
                    close(gcf);
                case 'SUB'
                    run_sub_mode(app);
                    hfig = open('result_sub.fig');
                    saveas(hfig,'result_sub.jpg');
                    app.Image.ImageSource='result_sub.jpg';
                    close(gcf);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.referenceButton = uibutton(app.UIFigure, 'push');
            app.referenceButton.ButtonPushedFcn = createCallbackFcn(app, @referenceButtonPushed, true);
            app.referenceButton.Text = 'reference';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.MODESwitchLabel = uilabel(app.UIFigure);
            app.MODESwitchLabel.Text = 'MODE';
            app.MODESwitch = uiswitch(app.UIFigure, 'slider');
            app.MODESwitch.Items = {'MAIN', 'SUB'};
            app.MODESwitch.Value = 'MAIN';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Hz'; 'dB'};
            app.UITable.RowName = {'1st'; '2nd'; '3rd'};
            app.UITable.ColumnEditable = true;
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speaker_tuner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end